classdef MainPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BiMatPanel                      matlab.ui.container.Panel
        ExportPanel                     matlab.ui.container.Panel
        ShowallPlotsButton              matlab.ui.control.Button
        TabEditField_2                  matlab.ui.control.EditField
        TabLabel                        matlab.ui.control.Label
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        ExportPlotsCheckBox             matlab.ui.control.CheckBox
        ExportButton                    matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        PlotsDropDown                   matlab.ui.control.DropDown
        PlotsDropDownLabel              matlab.ui.control.Label
        EditDefaultsButton              matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        StatusBar                       matlab.ui.control.EditField
        RunButton                       matlab.ui.control.Button
        FileNameexTestxlsxEditField     matlab.ui.control.EditField
        FileNameexTestxlsxEditFieldLabel  matlab.ui.control.Label
        TabDropDown                     matlab.ui.control.DropDown
        FileDropDown                    matlab.ui.control.DropDown
        UseaPreFormatedSheetButton      matlab.ui.control.Button
        MatrixPropertiesPanel           matlab.ui.container.Panel
        ListTabsButton                  matlab.ui.control.Button
        ViewMatrixButton                matlab.ui.control.Button
        Col_LabelsRangeEditField        matlab.ui.control.EditField
        Col_LabelsRangeEditFieldLabel   matlab.ui.control.Label
        Row_LabelsRangeEditField        matlab.ui.control.EditField
        Row_LabelsRangeEditFieldLabel   matlab.ui.control.Label
        MatrixRangeEditField            matlab.ui.control.EditField
        MatrixRangeEditFieldLabel       matlab.ui.control.Label
        TabEditField                    matlab.ui.control.EditField
        TabEditFieldLabel               matlab.ui.control.Label
        TabsCheckBox                    matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        GeneralPropertiesTab            matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SizeEditField                   matlab.ui.control.NumericEditField
        SizeLabel                       matlab.ui.control.Label
        Image2_2                        matlab.ui.control.Image
        ConnectanceorFillEditFieldLabel  matlab.ui.control.Label
        ConnectanceorFillEditField      matlab.ui.control.NumericEditField
        NumberofInteractionsEditFieldLabel  matlab.ui.control.Label
        NumberofInteractionsEditField   matlab.ui.control.NumericEditField
        NumberofColumnElementsEditFieldLabel  matlab.ui.control.Label
        NumberofColumnElementsEditField  matlab.ui.control.NumericEditField
        NumberofRowElementsEditFieldLabel  matlab.ui.control.Label
        NumberofRowElementsEditField    matlab.ui.control.NumericEditField
        NumberofElementsEditFieldLabel  matlab.ui.control.Label
        NumberofElementsEditField       matlab.ui.control.NumericEditField
        ModularityTab                   matlab.ui.container.Tab
        ModularityGridLayout            matlab.ui.container.GridLayout
        Image3                          matlab.ui.control.Image
        ShowModulesButton               matlab.ui.control.Button
        QrRatioofintextinterEditFieldLabel  matlab.ui.control.Label
        QrRatioofintextinterEditField   matlab.ui.control.NumericEditField
        QbStandardModularityMetricEditFieldLabel  matlab.ui.control.Label
        QbStandardModularityMetricEditField  matlab.ui.control.NumericEditField
        NNumberofModulesEditFieldLabel  matlab.ui.control.Label
        NNumberofModulesEditField       matlab.ui.control.NumericEditField
        KernighanLinTunningCheckBox     matlab.ui.control.CheckBox
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        PandZTab                        matlab.ui.container.Tab
        PlotButton                      matlab.ui.control.Button
        ModulesSwitch                   matlab.ui.control.ToggleSwitch
        PandZTable                      matlab.ui.control.Table
        NestednessTab                   matlab.ui.container.Tab
        NestednessGridLayout            matlab.ui.container.GridLayout
        NODFNestednessValueEditFieldLabel  matlab.ui.control.Label
        NODFNestednessValueEditField    matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        ViewNestedMatrixButton          matlab.ui.control.Button
        NODFColumnsValueEditFieldLabel  matlab.ui.control.Label
        NODFColumnsValueEditField       matlab.ui.control.NumericEditField
        NODFRowsValueEditFieldLabel     matlab.ui.control.Label
        NODFRowsValueEditField          matlab.ui.control.NumericEditField
        CompareTab                      matlab.ui.container.Tab
        PlotComparisonButton            matlab.ui.control.Button
        AddDataButton                   matlab.ui.control.Button
        ComparisonTable                 matlab.ui.control.Table
        BiMatAnalysisLabel              matlab.ui.control.Label
        DefaultsPanel                   matlab.ui.container.Panel
        ColsLabelEditField              matlab.ui.control.EditField
        ColsLabelEditField_2Label       matlab.ui.control.Label
        RowsLabelEditField              matlab.ui.control.EditField
        RowsLabelEditField_2Label       matlab.ui.control.Label
        OptionsDropDown                 matlab.ui.control.DropDown
        OptionsDropDownLabel            matlab.ui.control.Label
        UpdateButton                    matlab.ui.control.Button
        BiMatAnalysisDefaultsLabel      matlab.ui.control.Label
    end

%%% Properties that allow intialization of other windows %%%
    properties (Access = private)
        MatrixPage
        ListPage
        PandZPage
        ModulesPage
        PlotsPage
    end
    
%%% Main Page Functions %%%
    methods (Access = public)
        
        
 %% Calculation Functions %%
                
        
        % FilePath - Defines the path for file within the "Matrix_Files" folder
        %               - by combining the preset path with the manually inputted file name
        %           path = the address for the file of choice excluding the actual file name
        %               - this is currently locked to the Matrix Files folder within the master file
        %           file = the actual file name for the file
        %               - this is a manual input within the GUI
        function [path, file] = FilePath(app)
            file = app.FileNameexTestxlsxEditField.Value; 
            origin = "BiMat_Analysis\Matrix_Files\"; % this what forces the file loaction to be in the Matix_Files folder
            path = append(origin, file);
        end
        
        
        % DefineMatrix - Defines the Matrix using the ranges from the Matrix Properties panel
        %                   - by reading the selected file and using the ranges to find the corret data
        %           matrix_range = the cells that contain the actual data for the Adjacency Matrix
        %                   - this can defined manually or by using a predefined sheet
        %           row_labels_range = the cells that contain the row labels for the Adjacency Matrix
        %                   - this can defined manually or by using a predefined sheet
        %           col_labels_range = the cells that contain the column labels for the Adjacency Matrix
        %                   - this can defined manually or by using a predefined sheet
        %           tab = the tab within the specified sheet that the function will be using
        %           matrix = the  binary matrix that represents the Adjacency Matrix Data moving foward
        %           row_labels = the matrix the represents the Adjacency Matrix row labels moving foward
        %           col_labels = the matrix the represents the Adjacency Matrix column labels moving foward
        function [matrix, row_labels, col_labels] = DefineMatrix(app)
            
            [path,file] = FilePath(app);
            matrix_range = app.MatrixRangeEditField.Value;
            row_labels_range = app.Row_LabelsRangeEditField.Value;
            col_labels_range = app.Col_LabelsRangeEditField.Value;
            
            % Defining the Matrix depending on if the sheet has tabs or not
            if app.TabsCheckBox.Value == 1
                tab = app.TabEditField.Value;
                matrix = readmatrix(path,'Sheet',tab, 'Range', matrix_range);
                row_labels = readmatrix(path,'Sheet',tab,'Range',row_labels_range,'OutputType','string');
                col_labels = readmatrix(path,'Sheet',tab,'Range',col_labels_range,'OutputType','string');
            else 
                raw_tabs = sheetnames(file);
                tab = raw_tabs(1,1);
                matrix = readmatrix(path,'Sheet',tab, 'Range', matrix_range);
                row_labels = readmatrix(path,'Sheet',tab,'Range',row_labels_range,'OutputType','string');
                col_labels = readmatrix(path,'Sheet',tab,'Range',col_labels_range,'OutputType','string');
            end
            
            matrix = matrix > 0;
            matrix = double(matrix);
            
            app.StatusBar.Value = path; %shows the enitre file location in the status bar
            
        end
        
        
        % DefineBipartiteMatix - Defines the Bipartite Matrix
        %                   - by using the Bipartite function within the main folder of BiMat Analysis
        %           bp = the BipartiteMatrix defined by BiMat_Analysis' primary script
        %           bp_row_labels = the row labels of the bp matrix
        %           bp_col_labels = the col labels of the bp matrix
        function [bp, bp_row_labels, bp_col_labels] = DefineBipartiteMatrix(app)
            [matrix, row_labels, col_labels] = DefineMatrix(app);
            bp_row_labels = row_labels;
            bp_col_labels = col_labels;
            
            bp = Bipartite(matrix);
            bp.row_labels = bp_row_labels;
            bp.col_labels = bp_col_labels;
                        
        end
        
        
        % General Properties - Defines and Displays the General Properties of the Matrix
        %                   - by either calculating the values or calling on functions within MatrixFunctions.m
        %           webmatrix = the same as "matrix" but not necessarily binary
        %           num_of_species = the combined amount of both the row and column nodes in the Bipartite Matrix
        %           num_of_row_species = the amount rows in the Bipartite Matrix
        %           num_of_col_species = the amount Cols in the Bipartite Matrix
        %           num_of_interactions = the amount of rows and columns that interact(the number of cells that contian a "1")
        %           m_size = the amount of possible interactions (the amount of cells within the Bipartie Matrix)
        %           connectance = the Fill of "webmatrix"
        function [num_of_species, num_of_row_species, num_of_col_species, num_of_interactions, m_size, connectance] = GeneralProperties(app)
            [matrix, ~, ~] = DefineMatrix(app);
            webmatrix = 1 * (matrix > 0); 
            
            % Calculating Values
            num_of_species = size(matrix,1) + size(matrix,2);
            [num_of_row_species,num_of_col_species] = size(matrix);                    
            num_of_interactions = sum(webmatrix,"all");
            m_size = num_of_col_species * num_of_row_species;
            connectance = num_of_interactions/m_size;
            
            % Displaying Values
            app.NumberofElementsEditField.Value = num_of_species;
            app.NumberofRowElementsEditField.Value = num_of_row_species;
            app.NumberofColumnElementsEditField.Value = num_of_col_species;
            app.NumberofInteractionsEditField.Value = num_of_interactions;
            app.SizeEditField.Value = m_size;
            app.ConnectanceorFillEditField.Value = connectance;
            
        end
        
                
        % Modularity - Defines and Displays the Values for Modularity from the chosen alogrithm
        %                   - by calling upon the Detect function within the community folder of 
        %                     BiMat_Analysis once the specified algorithm is ran
        %           community = represents the Bipartite Adjacency Matrix that will be used to 
        %                       calcualte modularity 
        %           obj = a variable frame that allows for the Modularity values to be defined 
        %                 based on the correct algorithm
        %           N = number of modules
        %           Qb = standard modularity metric
        %           Qr = ratio of interactions (internal/external)
        %           A = matrix that represents the row interactions
        %           B = matrix that represents the column interactions
        function [N, Qb, Qr, A, B] = Modularity(app)
            
            [bp, ~, ~] = DefineBipartiteMatrix(app);
            
            % Selecting Algorithm with the Drop Down
            
            % LeadingEigenVector
            if strcmp(app.AlgorithmDropDown.Value, 'LeadingEigenVector') == true
                                               
                % KernighanLinTunning checkbox
                %if app.KernighanLinTunningCheckBox.Value == 0
                %    bp.community.DoKernighanLinTunning = false;
                %else
                %   bp.community.DoKernighanLinTunning = true;
                %end
                
                bp.community = LeadingEigenvector(bp.matrix);
                                               
            % ApadtiveBrim
            elseif strcmp(app.AlgorithmDropDown.Value, 'AdaptiveBrim') == true
                bp.community = AdaptiveBrim(bp.matrix);
                
            % LPBrim
            elseif strcmp(app.AlgorithmDropDown.Value, 'LPBrim') == true
                bp.community = LPBrim(bp.matrix);  
            end
            
            % Defining Modularity Values
            obj = Detect(bp.community);
            N = obj.N;               
            Qb = obj.Qb;
            Qr = obj.Qr;
            
            A = bp.community.row_modules;
            B = bp.community.col_modules;
            
            % Displaying Modularity Values
            app.NNumberofModulesEditField.Value = N;
            app.QbStandardModularityMetricEditField.Value = Qb;
            app.QrRatioofintextinterEditField.Value = Qr;
                  
        end
        
        
        % Nestedness - Defines and Displays the Values for Nestedness
        %                   - by calling upon the Detect function within the nestedness.m script 
        %                     within the nestedness folder of BiMat_Analysis
        %           obj = a variable frame that allows for the Nestedness values to be defined
        %           nestedness = the nestedness of the Bipartite matrix
        %           N_rows = 
        %           N_cols = 
        function [nestedness, N_rows, N_cols] = Nestedness(app)
                        
            [bp, ~, ~] = DefineBipartiteMatrix(app);
            
            % Defining Nestedness Values
            obj = Detect(bp.nestedness);
            nestedness = obj.N;
            N_rows = obj.N_rows;
            N_cols = obj.N_cols;
            
            % Displaying Nestedness Value
            app.NODFNestednessValueEditField.Value = nestedness;
            app.NODFRowsValueEditField.Value = N_rows;
            app.NODFColumnsValueEditField.Value = N_cols;
        end
        
        % NestedMatrix - Reorders the Bipartite Matrix into a nested matrix, as well as defines its row and column labels
        %                   - by calling upon the "SORT_MATRIX” function within the MatrixFunctions script
        %           sorted matrix = the sorted Bipartite matrix that is in the most nested form defined by the the "SORT_MATRIX” function within the MatrixFunctions script.
        %           ir = an array of the rows (index_row) indices positioned in their new positions. 
        %           ic = an array of the cols (index_col) indices positioned in their new positions. 
        %           sorted_row_labels = the sorted version of the bp_row_labels
        %           sorted_col_labels = the sorted version of the bp_col_labels
        %           col_index = a dummy variable defined by the specific column index from the ic array
        %           row index = a dummy variable defined by the specific row index from the ir array

        function [sorted_matrix, sorted_row_labels, sorted_col_labels] = NestedMatrix(app)
            [bp, bp_row_labels, bp_col_labels] = DefineBipartiteMatrix(app);
            [sorted_matrix, ir,ic] = MatrixFunctions.SORT_MATRIX(bp.matrix);
            
            % sorts the Row and Column labels to align with their orignal data entries
            [M,N] = size(bp.matrix);
            sorted_row_labels = strings([M,1]);
            sorted_col_labels = strings([N,1]);
            for i=1:N
                col_index = ic(i);
                sorted_col_labels(i,1) = bp_col_labels(1,col_index);
            end
            for i=1:M
                row_index = ir(i);
                sorted_row_labels(i,1) = bp_row_labels(row_index,1);
            end
        end
        
        % PandZ - Solves for the P and z values based on the Row or Col Modules
        %                   - by 
        %           C = the maxiumum value within the "A" matirx
        %           D = the maxiumum value within the "B" matirx
        %           Nm = the maxiumum value between "C" and "D"
        %           Nsm = an empty array with the ordered values of Nc and Nr
        %           Nr = the length of the row matrix ("A")
        %           Nc = the length of the row matrix ("B")
        %           kir = the Total Degree or the row nodes
        %           kic = the Total Degree or the column nodes
        %           kitr = the links from row node i to a specific module
        %           kitc = the links from column node i to a specific module
        %           P = a opertaional variable that allows for Pr or Pc to be defined
        %           Pr = the P values for the row nodes
        %           Pc = the P values for the column nodes
        %           kisr = the links from row node i to other nodes within its own module
        %           kisc = the links from column node i to other nodes within its own module
        %           kim = an array of each kis value for each module (for mean and stdev)
        %           zr = the z values for the row nodes
        %           zc = the z values for the col nodes
        function [Pr, zr, Pc, zc] = PandZ(app)
            
            %[matrix, row_labels, col_labels] = DefineMatrix(app);
            [bp, bp_row_labels, bp_col_labels] = DefineBipartiteMatrix(app);
            matrix = bp.matrix;
            
            [~, ~, ~, A, B] = Modularity(app);
            
            C=max(A);
            D=max(B);
            Nm=max(C,D);
            
            % Finding participation (connectivity)(with other modules)
            % Finding ki values for all row and column species
            Nr=length(A);
            Nc=length(B);
            kir=zeros(Nr,1);
            for i=1:Nr
                kir(i)=sum(matrix(i,:));
            end
            kic=zeros(Nc,1);
            for i=1:Nc
                kic(i)=sum(matrix(:,i));
            end
            
            % Finding kit values for all row and column species
            kitr=zeros(Nr,Nm);
            kitc=zeros(Nc,Nm);
            for i=1:Nr
                for j=1:Nc
                    if matrix(i,j)==1
                        mod=B(j);
                        kitr(i,mod)=kitr(i,mod)+1;
                    end
                end
            end
            for j=1:Nc
                for i=1:Nr
                    if matrix(i,j)==1
                        mod=A(i);
                        kitc(j,mod)=kitc(j,mod)+1;
                    end
                end
            end
            
            % Calculating P for each species
            Pr=zeros(Nr,1);
            Pc=zeros(Nc,1);
            for i=1:Nr
                P=1;
                for j=1:Nm
                    P=P-(kitr(i,j)/kir(i))^2;
                end
                Pr(i)=P;
            end
            for i=1:Nc
                P=1;
                for j=1:Nm
                    P=P-(kitc(i,j)/kic(i))^2;
                end
                Pc(i)=P;
            end
            
            % Finding intra-module degree (z) (within their own module)
            % Finding number of species in each module
            Nsm=zeros(Nm,1);
            for i=1:Nm
                for j=1:Nr
                    if A(j)==i
                        Nsm(i)=Nsm(i)+1;
                    end
                end
                for j=1:Nc
                    if B(j)==i
                        Nsm(i)=Nsm(i)+1;
                    end
                end
            end
            
            % Finding kis values for all row and column species
            kisr=zeros(Nr,1);
            kisc=zeros(Nc,1);
            for i=1:Nr
                kisr(i)=kitr(i,A(i));
            end
            for i=1:Nc
                kisc(i)=kitc(i,B(i));
            end
            
            % Creating matrix of each kis value for each module (for mean and stdev)
            kim=zeros(Nm,max(Nsm));
            kimIndex=ones(Nm,1); % This will be used to insert each new species into a blank space in the appropriate row
            for i=1:Nr
                mod=A(i);
                kim(mod,kimIndex(mod))=kisr(i);
                kimIndex(mod)=kimIndex(mod)+1;
            end
            for i=1:Nc
                mod=B(i);
                kim(mod,kimIndex(mod))=kisc(i);
                kimIndex(mod)=kimIndex(mod)+1;
            end
            
            % Finding mean and stdev values for each module's kis values
            meanstdev=zeros(Nm,2);
            for i=1:Nm
                meanstdev(i,1)=mean(kim(i,1:Nsm(i)));
                meanstdev(i,2)=std(kim(i,1:Nsm(i)));
            end
            
            % Calculating z for each species
            zr=zeros(Nr,1);
            zc=zeros(Nc,1);
            for i=1:Nr
                zr(i)=(kisr(i)-meanstdev(A(i),1))/(meanstdev(A(i),2));
            end
            for i=1:Nc
                zc(i)=(kisc(i)-meanstdev(B(i),1))/(meanstdev(B(i),2));
            end
            
            % Compiling final tables
            ROutput=[bp_row_labels,Pr,zr];
            COutput=[bp_col_labels',Pc,zc];
                       
        end
    
        
        % DefineRoles - Assigns each node to their role based on their P and Z values
        %                   - by iterating through each nodes correlated P and Z values
        %           N = the number or rows within the matrix
        %           ultra_peripheral = a redordered column array of the nodes that are defined by the ultra peripheral role
        %           peripheral = a redordered column array of the nodes that are defined by the peripheral role
        %           nonhub_connector = a redordered column array of the nodes that are defined by the non-hub connector role
        %           nonhub_kinless = a redordered column array of the nodes that are defined by the non-hub kinless role
        %           provincial = a redordered column array of the nodes that are defined by the provincial role
        %           connector = a redordered column array of the nodes that are defined by the connector role
        %           kinless = a redordered column array of the nodes that are defined by the kinless role
        %           roles = a combined array containg all of the role arrays above
        %           role_col = a combined column array of roles that remains in the orginial order 
        function [roles, ultra_peripheral, peripheral, nonhub_connector, nonhub_kinless, provincial, connector, kinless, role_col] = DefineRoles(app, p, z, labels)
            N = length(p); %Sets the length of the iteration
            
            % Intializes empty role arrays
            ultra_peripheral =[];
            peripheral = [];
            nonhub_connector =[];
            nonhub_kinless = [];
            provincial = [];
            connector = [];
            kinless = [];
            roles = strings([N,7]);
            role_col = strings([N,1]);
            
            % Fills the role arrays
            for i=1:N
                % Non-Hub Roles
                if z(i) < 2.5
                    if p(i) <= .1
                        ultra_peripheral = [ultra_peripheral; labels(i)];
                        role_col(i, 1) = 'Ultra-Peripheral';
                    elseif p(i) >= .1 && p(i) <= .625
                        peripheral = [peripheral; labels(i)];
                        role_col(i, 1) = 'Non-Hub Peripheral';
                    elseif p(i) >= .625 && p(i) <= .8
                        nonhub_connector =[nonhub_connector; labels(i)];
                        role_col(i, 1) = 'Non-Hub Connector';
                    elseif p(i) >= .8
                        nonhub_kinless = [nonhub_kinless; labels(i)];
                        role_col(i, 1) = 'Non-Hub Kinless';
                    end
                % Hub Roles
                elseif z(i) >= 2.5
                    if p(i) <= .3
                        provincial = [provincial; labels(i)];
                        role_col(i, 1) = 'Provincial';
                    elseif p(i) >= .3 && p(i) <= .75
                        connector = [connector; labels(i)];
                        role_col(i, 1) = 'Connector';
                    elseif p(i) >= .75
                        kinless = [kinless; labels(i)];
                        role_col(i, 1) = 'Non-Hub Kinless';
                    end
                end
            end
            
            % Reordering the Nodes
            for i=1:length(ultra_peripheral)
                roles(i,1) = ultra_peripheral(i);
            end
            for i=1:length(peripheral)
                roles(i,2) = peripheral(i);
            end
            for i=1:length(nonhub_connector)
                roles(i,3) = nonhub_connector(i);
            end
            for i=1:length(nonhub_kinless)
                roles(i,4) = nonhub_kinless(i);
            end
            for i=1:length(provincial)
                roles(i,5) = provincial(i);
            end
            for i=1:length(connector)
                roles(i,6) = connector(i);
            end
            for i=1:length(kinless)
                roles(i,7) = kinless(i);
            end
            
        end
        
        
 %% GUI Interaction Functions %%
        
        
        % RunBiMat - The Main function of the GUI that steps though the analysis functions
        function RunBiMat(app)
            
            % General Properties - Defines and Displays the General Properties of the Matrix
            GeneralProperties(app);
            
            % Modularity - Defines and Displays the Values for Modulairty from the chosen alogrithum
            Modularity(app);
            
            % Nestedness - Defines and Displays the Values for Nestedness
            Nestedness(app);
            
            % FillPandZ - Combines the P and Z values with their roles and Displays them
            FillPandZ(app);
            
            % Progress Lamp
            app.Lamp.Color = '0.00,1.00,0.00'; % Green
            
        end
        
        
        % GeneratePlots - Shows the Plot that is Chosen in the Dropdown as a seperate window
        function GeneratePlots(app)
            
            [bp, ~, ~] = DefineBipartiteMatrix(app);
            f = figure('Visible','on');
                                   
            if strcmp(app.PlotsDropDown.Value, 'Modular Graph')
                bp.plotter.PlotModularGraph();
            elseif strcmp(app.PlotsDropDown.Value, 'Matrix')
                bp.plotter.PlotMatrix();
            elseif strcmp(app.PlotsDropDown.Value, 'Modular Matrix')
                bp.plotter.PlotModularMatrix();
            elseif strcmp(app.PlotsDropDown.Value, 'Nested Matrix')
                bp.plotter.PlotNestedMatrix();
            end
           
        end
        
        
        % GenerateTabOptions - Generates the Tab options for premade Matrices
        %                   - by calling upon the correct ###MatrixProperties script
        %           default_tab = allows for the tab drop down to be filled once a file is chosen
        %           tab_name = the selected tab within the dropdown
        function GenerateTabOptions(app, file)
                                 
            if strcmp(app.FileDropDown.Value, 'Bipart.xlsx')
                default_tab = 'TAMUS21';
                tab_name = default_tab;
                
                [~, ~, ~, tabs] = BipartMatrixProperties(tab_name, file);
                app.TabDropDown.Items = tabs;
                
            elseif strcmp(app.FileDropDown.Value, 'Test.xlsx')
                default_tab = 'Test1';
                tab_name = default_tab;
                
                [~, ~, ~, tabs] = TestMatrixProperties(tab_name, file);
                app.TabDropDown.Items = tabs;
                
            elseif strcmp(app.FileDropDown.Value, 'PowerGrid.xlsx')
                default_tab = 'Original 5 Bus';
                tab_name = default_tab;
                
                [~, ~, ~, tabs] = PowerGridMatrixProperties(tab_name, file);
                app.TabDropDown.Items = tabs;
                
            else
                app.TabDropDown.Items = {'N/A'};
                
            end
        end
        
        
        % ConstructPremadeMatrix - Fills the Matrix ranges using the Pre-set ranges within the sheets persepctive matlab script
        %                   - by calling upon the correct ###MatrixProperties script
        function [matrix_range, row_labels_range, col_labels_range] = ConstructPremadeMatrix(app)
            
            [~,file] = FilePath(app);
            tab_name = app.TabDropDown.Value;
            
            if strcmp(app.FileDropDown.Value, "Bipart.xlsx")
                app.TabsCheckBox.Value = 1;
                app.TabEditField.Visible = "on";
                [matrix_range, row_labels_range, col_labels_range, ~] = BipartMatrixProperties(tab_name, file);
            elseif strcmp(app.FileDropDown.Value, "Test.xlsx")
                [matrix_range, row_labels_range, col_labels_range, ~] = TestMatrixProperties(tab_name, file);
            elseif strcmp(app.FileDropDown.Value, "PowerGrid.xlsx")
                [matrix_range, row_labels_range, col_labels_range, ~] = PowerGridMatrixProperties(tab_name, file);
            end
            
        end
        
        
        % FillPandZ - Combines the P and Z values with their roles and Displays them
        %           p = a column array of the P values(either based on rows or columns)
        %           z = a column array of the the z values(either based on rows or columns)
        %           labels = a column array of the the labels(either based on rows or columns)
        %           label = the labels value chosen on the default page(either based on rows or columns)
        %                - this can defined manually or by using the predefined options
        function FillPandZ(app)
            [Pr, zr, Pc, zc] = PandZ(app);
            
            [~, bp_row_labels, bp_col_labels] = DefineBipartiteMatrix(app);
            
            % Defines Value based on either Rows or Columns
            if strcmp(app.ModulesSwitch.Value, 'Rows')
                p = Pr;
                z = zr;
                labels = bp_row_labels;
                label = app.RowsLabelEditField.Value;
            elseif strcmp(app.ModulesSwitch.Value, 'Columns')
                p = Pc;
                z = zc;
                labels = transpose(bp_col_labels);
                label = app.ColsLabelEditField.Value;
            end
            
            [~, ~, ~, ~, ~, ~, ~, ~, role_col] = DefineRoles(app, p, z, labels);
            
            % Combines the P and Z scores with their node label and role.
            app.PandZTable.Data = horzcat(labels, p, z, role_col);
            
            % Displays the combined array
            app.PandZTable.ColumnName = {label, 'P-Value', 'z-Value', 'Role'};
            
        end
        
        
        % LaunchPandZ - Opens the PandZPage and plots the P and Z values 
        % on a scatter plot as well as displays their roles
        function LaunchPandZ(app, labels, p, z, label)
            
            [~, file] = FilePath(app);
            tab = app.TabEditField.Value;
            [roles, ~, ~, ~, ~, ~, ~, ~, role_col] = DefineRoles(app, p, z, labels);
                                        
            % Opens the P and Z app
            app.PandZPage = PandZPage(file, tab, labels, p, z, label, roles, role_col);
        end
    
        
        % ExporttoExcel - Combines all of the GUI results into a single cell array and 
        % prepares it to be exported to a specified excel sheet and tab
        function results = ExporttoExcel(app)
            file = append('BiMat_Analysis\Results\' , app.FileEditField.Value);
            tab = app.TabEditField_2.Value;
            
            [bp, bp_row_labels, bp_col_labels] = DefineBipartiteMatrix(app);
            matrix = bp.matrix;
            [num_of_species, num_of_row_species, num_of_col_species, num_of_interactions, m_size, connectance] = GeneralProperties(app);
            [N, Qb, Qr, ~, ~] = Modularity(app);
            algorithm = app.AlgorithmDropDown.Value;
            [nestedness, N_rows, N_cols] = Nestedness(app);
            [sorted_matrix, sorted_row_labels, sorted_col_labels] = NestedMatrix(app);
            [Pr, zr, Pc, zc] = PandZ(app);
            [~, ~, ~, ~, ~, ~, ~, ~, r_role_col] = DefineRoles(app, Pr, zr, bp_row_labels);
            [~, ~, ~, ~, ~, ~, ~, ~, c_role_col] = DefineRoles(app, Pc, zc, bp_col_labels);
            r_label = app.RowsLabelEditField.Value;
            c_label = app.ColsLabelEditField.Value;
            Title = append(app.FileEditField.Value,' - ',tab);
            
            if length(Pr) > length(Pc)
                n1 = (22 + length(Pr) + 2);
            else
                n1 = (22 + length(Pc) + 2);
            end
            
            n2 = length(bp_row_labels)+2;
            n3 = length(sorted_row_labels)+1;
            
            if (length(sorted_col_labels)+1) > 8
                m = length(sorted_col_labels)+1;
            else
                m =8;                
            end
                       
            results = cell((n1+n2+n3),m);
            
            results{1,2} = Title;
            
            results{3,1} = 'General Properties';
            results{4,1} = 'Number of Elements';
            results{4,2} = num_of_species;
            results{5,1} = 'Number of Row Elements';
            results{5,2} = num_of_row_species;
            results{6,1} = 'Number of Col Elements';
            results{6,2} = num_of_col_species;
            results{7,1} = 'Number of Interactions';
            results{7,2} = num_of_interactions;
            results{8,1} = 'Size';
            results{8,2} = m_size;
            results{9,1} = 'Connectance or Fill';
            results{9,2} = connectance;
            
            results{11,1} = 'Modulairty';
            results{12,1} = 'Algorithum';
            results{12,2} = algorithm;
            results{13,1} = 'N - Number of Modules';
            results{13,2} = N;
            results{14,1} = 'Qb - Standard Modularity Metric';
            results{14,2} = Qb;
            results{15,1} = 'Qr - Ratio of int/ext interactions';
            results{15,2} = Qr;
            
            results{17, 1} = 'Nestedness';
            results{18, 1} = 'NODF Nestedness Value';
            results{18, 2} = nestedness;
            results{19, 1} = 'NODF Rows Value';
            results{19, 2} = N_rows;
            results{20, 1} = 'NODF Columns Value';
            results{20, 2} = N_cols;
            
            results{22,1} = r_label;
            results{22,2} = 'P-Value';
            results{22,3} = 'z-Value';
            results{22,4} = 'Role';
            for i=1:(length(Pr))
                results{(22+i),1} = bp_row_labels(i);
                results{(22+i),2} = Pr(i);
                results{(22+i),3} = zr(i);
                results{(22+i),4} = r_role_col(i);
            end
            
            
            results{22,6} = c_label;
            results{22,7} = 'P-Value';
            results{22,8} = 'z-Value';
            results{22,9} = 'Role';
            for i=1:(length(Pc))
                results{(22+i),6} = bp_col_labels(i);
                results{(22+i),7} = Pc(i);
                results{(22+i),8} = zc(i);
                results{(22+i),9} = c_role_col(i);
            end
            
            results{n1,1} = 'Matrix';
            for i=1:length(bp_row_labels)
                results{(n1+i), 1} = bp_row_labels(i);
                for j=1:length(bp_col_labels)
                    results{n1,(j+1)} = bp_col_labels(j);
                    results{(n1+i),(j+1)} = matrix(i,j);
                end
            end
            
            results{(n1+n2),1} = 'Nested Matrix';
            for i=1:length(sorted_row_labels)
                results{((n1+n2)+i), 1} = sorted_row_labels(i);
                for j=1:length(sorted_col_labels)
                    results{(n1+n2),(j+1)} = sorted_col_labels(j);
                    results{((n1+n2)+i),(j+1)} = sorted_matrix(i,j);
                end
            end
            
        end
       
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            file = app.FileDropDown.Value;
            app.MatrixPropertiesPanel.Visible = 'off';
            app.TabEditField.Visible = 'off';
            app.TabEditFieldLabel.Visible = 'off';
            app.ShowallPlotsButton.Visible = 'off';
            
            GenerateTabOptions(app, file);
            app.Lamp.Color = 'red';
            
            app.BiMatPanel.Visible = 'off';
            app.DefaultsPanel.Visible = 'on';
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;
            
            app.Lamp.Color = 'red';
            
            % sets the row and col labels as well as the file name for 
            % the results based on the default chosen
            if strcmp(value, 'MakerSpace')
                app.RowsLabelEditField.Value = 'Students';
                app.ColsLabelEditField.Value = 'Tools';
                app.FileEditField.Value = 'MakerSpaceResults.xlsx';
            elseif strcmp(value, 'Ecosystem')
                app.RowsLabelEditField.Value = 'Animals';
                app.ColsLabelEditField.Value = 'Plants';
                app.FileEditField.Value = 'EcosystemResults.xlsx';
            elseif strcmp(value, 'Social Network')
                app.RowsLabelEditField.Value = 'People';
                app.ColsLabelEditField.Value = 'Events';
            elseif strcmp(value, 'Transportation Network')
                app.RowsLabelEditField.Value = 'Locations';
                app.ColsLabelEditField.Value = 'Routes';
            elseif strcmp(value, 'Power Grid')
                app.RowsLabelEditField.Value = 'Suppliers';
                app.ColsLabelEditField.Value = 'Receivers';
                app.FileEditField.Value = 'PowerGridResults.xlsx';
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.DefaultsPanel.Visible = 'off';
            app.BiMatPanel.Visible = 'on';
        end

        % Button pushed function: EditDefaultsButton
        function EditDefaultsButtonPushed(app, event)
            app.BiMatPanel.Visible = 'off';
            app.DefaultsPanel.Visible = 'on';
        end

        % Button pushed function: UseaPreFormatedSheetButton
        function UseaPreFormatedSheetButtonPushed(app, event)
            app.MatrixPropertiesPanel.Visible = 'on';
            
            app.FileNameexTestxlsxEditField.Value = app.FileDropDown.Value;
            
            % Defines the Ranges based on the premade selections
            [matrix_range, row_labels_range, col_labels_range] = ConstructPremadeMatrix(app);
            
            % Shows the Ranges based on the premade selections
            app.MatrixRangeEditField.Value = matrix_range;
            app.Row_LabelsRangeEditField.Value = row_labels_range;
            app.Col_LabelsRangeEditField.Value = col_labels_range;
            app.TabEditField.Value = app.TabDropDown.Value;
            
        end

        % Value changed function: FileDropDown
        function FileDropDownValueChanged(app, event)
            file = app.FileDropDown.Value;
            app.FileNameexTestxlsxEditField.Value = file;
            GenerateTabOptions(app, file);
            
            % Emptys the Ranges so that user knows to update them with 
            % the "Use Preformated Sheet" button
            app.MatrixRangeEditField.Value = '';
            app.Row_LabelsRangeEditField.Value = '';
            app.Col_LabelsRangeEditField.Value = '';
            
            app.ShowallPlotsButton.Visible = 'off';
            
            app.Lamp.Color = 'red';
        end

        % Value changed function: TabDropDown
        function TabDropDownValueChanged(app, event)
            value = app.TabDropDown.Value;
            
            % Emptys the Ranges so that user knows to update them with 
            % the "Use Preformated Sheet" button
            app.MatrixRangeEditField.Value = ' ';
            app.Row_LabelsRangeEditField.Value = ' ';
            app.Col_LabelsRangeEditField.Value = ' ';
            
            app.ShowallPlotsButton.Visible = 'off';
            
            app.TabEditField_2.Value = value; % sets the value for the tab to export to
            
            app.Lamp.Color = 'red';
        end

        % Button pushed function: ViewMatrixButton
        function ViewMatrixButtonPushed(app, event)
            [matrix,row_labels, col_labels] = DefineMatrix(app);
            [~, file] = FilePath(app);
            
            % defines the tab based on if the tabs checkbox is filled or not
            if app.TabsCheckBox.Value == 1
                tab = app.TabEditField.Value;
            else 
                raw_tabs = sheetnames(file);
                tab = raw_tabs(1,1);
            end
            
            app.MatrixPage = MatrixPage(matrix, row_labels, col_labels, file, tab); % opens and fills the MatrixPage
            
            
        end

        % Button pushed function: ListTabsButton
        function ListTabsButtonPushed(app, event)
            [~, file] = FilePath(app);
            tabs = sheetnames(file);
            app.ListPage = ListPage(tabs); % Opens the ListPage
        end

        % Value changed function: TabsCheckBox
        function TabsCheckBoxValueChanged(app, event)
            value = app.TabsCheckBox.Value;
            
            % Shows the Tab edit field in the "Matrix Properties Panel" 
            % if the the Tab checkbox is filled
            if value == 1
                app.TabEditField.Visible = 'on';
                app.TabEditFieldLabel.Visible = 'on';
            elseif value == 0
                app.TabEditField.Visible = 'off';
                app.TabEditFieldLabel.Visible = 'off';
            end
        end

        % Value changing function: FileNameexTestxlsxEditField
        function FileNameexTestxlsxEditFieldValueChanging(app, event)
            %changingValue = event.Value;
            app.MatrixPropertiesPanel.Visible = 'on';
        end

        % Value changed function: FileNameexTestxlsxEditField
        function FileNameexTestxlsxEditFieldValueChanged(app, event)
            app.MatrixPropertiesPanel.Visible = 'on';
            
            % Emptys the Ranges so that user knows to update them with 
            % the "Use Preformated Sheet" button
            app.MatrixRangeEditField.Value = ' ';
            app.Row_LabelsRangeEditField.Value = ' ';
            app.Col_LabelsRangeEditField.Value = ' ';
            
            app.Lamp.Color = 'red';
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            [path, ~] = FilePath(app);
            
            % Displays error message if the matirx has not ben specified
            if isempty(app.FileNameexTestxlsxEditField.Value)
                app.StatusBar.Value = "Specify a Matrix";
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            % Displays error message if the matirx range has not ben specified
            elseif isempty(app.MatrixRangeEditField.Value)
                app.StatusBar.Value = "Specify a Matrix Range";
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            % Displays error message if the row labels range has not ben specified
            elseif isempty(app.Row_LabelsRangeEditField.Value)
                app.StatusBar.Value = "Specify a Row Labels Range";
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            % Displays error message if the col labels range has not ben specified
            elseif isempty(app.Col_LabelsRangeEditField.Value)
                app.StatusBar.Value = "Specify a Col Labels Range";
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            else
            % Run Analysis
                app.StatusBar.Value = path;
                app.StatusBar.FontWeight = 'normal';
                app.StatusBar.FontColor = 'black';
                app.Lamp.Color = '0.93,0.69,0.13';
                drawnow();
                RunBiMat(app);
            end
           
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
            
            % Shows the KernighanLinTunning Check box if the 
            % LeadingEigenVector Algorithum is chosen
            if strcmp(value, "LeadingEigenVector")
                app.KernighanLinTunningCheckBox.Visible = 'on';
            else
                app.KernighanLinTunningCheckBox.Visible = 'off';
            end
            
            % Reruns the Modularity function for the 
            % algorithum thats chosen
            Modularity(app);
            
        end

        % Button pushed function: ShowModulesButton
        function ShowModulesButtonPushed(app, event)
            [~, bp_row_labels, bp_col_labels] = DefineBipartiteMatrix(app);
            [N, ~, ~, ~, ~] = Modularity(app);
            [matrix, ~, ~] = DefineMatrix(app);
            
            %% Selecting Algorithm with the Drop Down
            % LeadingEigenVector
            if strcmp(app.AlgorithmDropDown.Value, 'LeadingEigenVector') == true
                algorithm = 'LeadingEigenVector';
                                                             
            % ApadtiveBrim
            elseif strcmp(app.AlgorithmDropDown.Value, 'AdaptiveBrim') == true
                algorithm = 'AdaptiveBrim';
                
            % LPBrim
            elseif strcmp(app.AlgorithmDropDown.Value, 'LPBrim') == true
                algorithm = 'LPBrim';
            end
                        
            app.ModulesPage = ModulesPage(algorithm, N, matrix, bp_row_labels, bp_col_labels);
        end

        % Value changed function: ModulesSwitch
        function ModulesSwitchValueChanged(app, event)
            app.Lamp.Color = '0.93,0.69,0.13';
            drawnow();
            
            % Reruns the FillPandZ function for the new switch value
            FillPandZ(app);
            
            app.Lamp.Color = 'green';
            drawnow();
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.Lamp.Color = '0.93,0.69,0.13';
            drawnow();
            
            % Displays error message if Anaylisis has not been run yet
            if isempty(app.PandZTable.Data)
                app.StatusBar.Value = 'Please Run the Analysis';
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            % Opens the PandZPage based on the Modules Switch Value (rows or columns)
            else
                [Pr, zr, Pc, zc] = PandZ(app);
                %[~, row_labels, col_labels] = DefineMatrix(app);
                [~, bp_row_labels, bp_col_labels] = DefineBipartiteMatrix(app);
                
            
                if strcmp(app.ModulesSwitch.Value, 'Rows')
                    label = app.RowsLabelEditField.Value;
                    LaunchPandZ(app, bp_row_labels, Pr, zr, label);
                elseif strcmp(app.ModulesSwitch.Value, 'Columns')
                    label = app.ColsLabelEditField.Value;
                    col_labels = transpose(bp_col_labels); %Converts the column labels into a vertical array
                    LaunchPandZ(app, col_labels, Pc, zc, label);
                end
            end
            
            app.Lamp.Color = 'green';
            drawnow();
        end

        % Button pushed function: ViewNestedMatrixButton
        function ViewNestedMatrixButtonPushed(app, event)
            [~, file] = FilePath(app);
            [sorted_matrix, sorted_row_labels, sorted_col_labels] = NestedMatrix(app);
                       
            % defines the tab based on if the tabs checkbox is filled or not
            if app.TabsCheckBox.Value == 1
                tab = app.TabEditField.Value;
            else 
                raw_tabs = sheetnames(file);
                tab = raw_tabs(1,1);
            end
                        
            app.MatrixPage = MatrixPage(sorted_matrix, sorted_row_labels, sorted_col_labels, file, tab); % opens and fills the MatrixPage
            
        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
            app.Lamp.Color = '0.93,0.69,0.13';
            drawnow();
            
            % Defines the Title of the Combined Results based on if the tabs check box is checked or not
            if app.TabsCheckBox.Value == true
                Title = app.TabEditField.Value;
            else
                Title = app.FileNameexTestxlsxEditField.Value;
            end
            
            results = ExporttoExcel(app); %Defines the combined results
            
            result = results(3:20, 2); %Defines a temporary array of the most recently ran results
            result{1,1} = (Title); % adds the Title name to the temporary result array
            
            app.ComparisonTable.Data = [app.ComparisonTable.Data,result]; % adds temporary result to the data table
            app.ComparisonTable.RowName = results(3:20,1); % adds the row labels to the data table
            
            app.Lamp.Color = 'green';
            drawnow();
        end

        % Button pushed function: PlotComparisonButton
        function PlotComparisonButtonPushed(app, event)
            data = app.ComparisonTable.Data;
            display(data);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            app.Lamp.Color = '0.93,0.69,0.13';
            drawnow();
            
            % Generates Plot chosen in the "Plots" dropdown
            GeneratePlots(app);
            
            app.Lamp.Color = 'green';
            drawnow();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.Lamp.Color = '0.93,0.69,0.13';
            drawnow();
            
            [bp, ~, ~] = DefineBipartiteMatrix(app);
            [path, ~] = FilePath(app);
            location = append("BiMat_Analysis\Results\",app.FileEditField.Value);
            tab = app.TabEditField_2.Value;
            
            % Displays error message if Anaylisis has 
            % not been run yet
            if app.NumberofElementsEditField.Value == 0
                app.StatusBar.Value = 'Please Run the Analysis';
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            % Displays error message if the filename for the 
            % export has not ben specified
            elseif strcmp(app.FileEditField.Value, '')
                app.StatusBar.Value = 'Please Specify a File';
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            % Displays error message if the tab for the 
            % export has not ben specified
            elseif strcmp(tab, '')
                app.StatusBar.Value = 'Please Specify a Tab';
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            % Exports the Results using the ExporttoExcel function
            else
                results = ExporttoExcel(app);
                %display(results);
                writecell(results, location, 'Sheet', tab)
                app.StatusBar.Value = path;
                app.StatusBar.FontWeight = 'normal';
                app.StatusBar.FontColor = 'black';
                
                % Exports all of the Plots to the "Graphs" folder within 
                % "Results" folder of "BiMat_Analysis"
                if app.ExportPlotsCheckBox.Value == true
                    app.ShowallPlotsButton.Visible = 'on';
                    
                    % Modular Graph
                    plt = "ModularGraph.jpg";
                    plt_path = append("BiMat_Analysis\Results\Graphs\", plt);
                    f = figure('Visible','off');
                    bp.plotter.PlotModularGraph();
                    exportgraphics(f,plt_path)
                                        
                    % Matrix Plot
                    plt = "MatrixPlot.jpg";
                    plt_path = append("BiMat_Analysis\Results\Graphs\", plt);
                    f = figure('Visible','off');
                    bp.plotter.PlotMatrix();
                    exportgraphics(f,plt_path)
                    
                    % Modular Matrix Plot
                    plt = "ModularMatrixPlot.jpg";
                    plt_path = append("BiMat_Analysis\Results\Graphs\", plt);
                    f = figure('Visible','off');
                    bp.plotter.PlotModularMatrix();
                    exportgraphics(f,plt_path)
                    
                    % Nested Matrix Plot
                    plt = "NestedMatrixPlot.jpg";
                    plt_path = append("BiMat_Analysis\Results\Graphs\", plt);
                    f = figure('Visible','off');
                    bp.plotter.PlotNestedMatrix();
                    exportgraphics(f,plt_path)
                
                end
                
            end
            
            app.Lamp.Color = 'green';
            drawnow();
        end

        % Button pushed function: ShowallPlotsButton
        function ShowallPlotsButtonPushed(app, event)
            [~, file] = FilePath(app);
            
            % defines the tab based on if the tabs checkbox is filled or not
            if app.TabsCheckBox.Value == 1
                tab = app.TabEditField.Value;
            else 
                raw_tabs = sheetnames(file);
                tab = raw_tabs(1,1);
            end
            
            app.PlotsPage = PlotsPage(file,tab);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DefaultsPanel = uipanel(app.UIFigure);
            app.DefaultsPanel.Visible = 'off';
            app.BiMatAnalysisDefaultsLabel = uilabel(app.DefaultsPanel);
            app.BiMatAnalysisDefaultsLabel.Text = 'Bi-Mat Analysis Defaults';
            app.UpdateButton = uibutton(app.DefaultsPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.OptionsDropDownLabel = uilabel(app.DefaultsPanel);
            app.OptionsDropDownLabel.Text = 'Options :';
            app.OptionsDropDown = uidropdown(app.DefaultsPanel);
            app.OptionsDropDown.Items = {'Custom', 'MakerSpace', 'Ecosystem', 'Social Network', 'Transportation Network', 'Power Grid'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Custom';
            app.RowsLabelEditField_2Label = uilabel(app.DefaultsPanel);
            app.RowsLabelEditField_2Label.Text = 'Rows Label :';
            app.RowsLabelEditField = uieditfield(app.DefaultsPanel, 'text');
            app.ColsLabelEditField_2Label = uilabel(app.DefaultsPanel);
            app.ColsLabelEditField_2Label.Text = 'Cols Label :';
            app.ColsLabelEditField = uieditfield(app.DefaultsPanel, 'text');
            app.BiMatAnalysisLabel = uilabel(app.UIFigure);
            app.BiMatAnalysisLabel.Text = 'BiMat Analysis';
            app.BiMatPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.BiMatPanel);
            app.GeneralPropertiesTab = uitab(app.TabGroup);
            app.GeneralPropertiesTab.Title = 'General Properties';
            app.GridLayout = uigridlayout(app.GeneralPropertiesTab);
            app.NumberofElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofElementsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofElementsEditFieldLabel.Text = 'Number of Elements';
            app.NumberofRowElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofRowElementsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofRowElementsEditFieldLabel.Text = 'Number of Row Elements';
            app.NumberofColumnElementsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofColumnElementsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofColumnElementsEditFieldLabel.Text = 'Number of Column Elements';
            app.NumberofInteractionsEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofInteractionsEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofInteractionsEditFieldLabel.Text = 'Number of Interactions';
            app.ConnectanceorFillEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConnectanceorFillEditFieldLabel = uilabel(app.GridLayout);
            app.ConnectanceorFillEditFieldLabel.Text = 'Connectance or Fill';
            app.Image2_2 = uiimage(app.GridLayout);
            app.Image2_2.ImageSource = 'Nestedness vs Modularity Plots.jpg';
            app.SizeLabel = uilabel(app.GridLayout);
            app.SizeLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.GridLayout, 'numeric');
            app.ModularityTab = uitab(app.TabGroup);
            app.ModularityTab.Title = 'Modularity';
            app.ModularityGridLayout = uigridlayout(app.ModularityTab);
            app.AlgorithmDropDown = uidropdown(app.ModularityGridLayout);
            app.AlgorithmDropDown.Items = {'LeadingEigenVector', 'AdaptiveBrim', 'LPBrim'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'LeadingEigenVector';
            app.AlgorithmDropDownLabel = uilabel(app.ModularityGridLayout);
            app.AlgorithmDropDownLabel.Text = 'Algorithm:';
            app.KernighanLinTunningCheckBox = uicheckbox(app.ModularityGridLayout);
            app.KernighanLinTunningCheckBox.Text = 'KernighanLinTunning';
            app.KernighanLinTunningCheckBox.Value = true;
            app.NNumberofModulesEditField = uieditfield(app.ModularityGridLayout, 'numeric');
            app.NNumberofModulesEditFieldLabel = uilabel(app.ModularityGridLayout);
            app.NNumberofModulesEditFieldLabel.Text = 'N (Number of Modules)';
            app.QbStandardModularityMetricEditField = uieditfield(app.ModularityGridLayout, 'numeric');
            app.QbStandardModularityMetricEditFieldLabel = uilabel(app.ModularityGridLayout);
            app.QbStandardModularityMetricEditFieldLabel.Text = 'Qb (Standard Modularity Metric)';
            app.QrRatioofintextinterEditField = uieditfield(app.ModularityGridLayout, 'numeric');
            app.QrRatioofintextinterEditFieldLabel = uilabel(app.ModularityGridLayout);
            app.QrRatioofintextinterEditFieldLabel.Text = 'Qr (Ratio of int/ext inter)';
            app.ShowModulesButton = uibutton(app.ModularityGridLayout, 'push');
            app.ShowModulesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowModulesButtonPushed, true);
            app.ShowModulesButton.Text = 'Show Modules';
            app.Image3 = uiimage(app.ModularityGridLayout);
            app.Image3.ImageSource = 'P and Z.jpg';
            app.PandZTab = uitab(app.TabGroup);
            app.PandZTab.Title = 'P and Z';
            app.PandZTable = uitable(app.PandZTab);
            app.PandZTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PandZTable.RowName = {};
            app.ModulesSwitch = uiswitch(app.PandZTab, 'toggle');
            app.ModulesSwitch.Items = {'Rows', 'Columns'};
            app.ModulesSwitch.ValueChangedFcn = createCallbackFcn(app, @ModulesSwitchValueChanged, true);
            app.ModulesSwitch.Value = 'Rows';
            app.PlotButton = uibutton(app.PandZTab, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NestednessTab = uitab(app.TabGroup);
            app.NestednessTab.Title = 'Nestedness';
            app.NestednessGridLayout = uigridlayout(app.NestednessTab);
            app.NODFRowsValueEditField = uieditfield(app.NestednessGridLayout, 'numeric');
            app.NODFRowsValueEditFieldLabel = uilabel(app.NestednessGridLayout);
            app.NODFRowsValueEditFieldLabel.Text = 'NODF (Rows Value)';
            app.NODFColumnsValueEditField = uieditfield(app.NestednessGridLayout, 'numeric');
            app.NODFColumnsValueEditFieldLabel = uilabel(app.NestednessGridLayout);
            app.NODFColumnsValueEditFieldLabel.Text = 'NODF (Columns Value)';
            app.ViewNestedMatrixButton = uibutton(app.NestednessGridLayout, 'push');
            app.ViewNestedMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ViewNestedMatrixButtonPushed, true);
            app.ViewNestedMatrixButton.Text = 'View Nested Matrix';
            app.Image = uiimage(app.NestednessGridLayout);
            app.Image.ImageSource = 'Nestedness Plots.png';
            app.NODFNestednessValueEditField = uieditfield(app.NestednessGridLayout, 'numeric');
            app.NODFNestednessValueEditFieldLabel = uilabel(app.NestednessGridLayout);
            app.NODFNestednessValueEditFieldLabel.Text = 'NODF (Nestedness Value)';
            app.CompareTab = uitab(app.TabGroup);
            app.CompareTab.Title = 'Compare';
            app.ComparisonTable = uitable(app.CompareTab);
            app.ComparisonTable.ColumnName = '';
            app.ComparisonTable.RowName = {};
            app.AddDataButton = uibutton(app.CompareTab, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Text = 'Add Data';
            app.PlotComparisonButton = uibutton(app.CompareTab, 'push');
            app.PlotComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @PlotComparisonButtonPushed, true);
            app.PlotComparisonButton.Visible = 'off';
            app.PlotComparisonButton.Text = 'Plot Comparison';
            app.TabsCheckBox = uicheckbox(app.BiMatPanel);
            app.TabsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TabsCheckBoxValueChanged, true);
            app.TabsCheckBox.Text = 'Tabs';
            app.MatrixPropertiesPanel = uipanel(app.BiMatPanel);
            app.MatrixPropertiesPanel.Title = 'Matrix Properties';
            app.TabEditFieldLabel = uilabel(app.MatrixPropertiesPanel);
            app.TabEditFieldLabel.Text = 'Tab:';
            app.TabEditField = uieditfield(app.MatrixPropertiesPanel, 'text');
            app.MatrixRangeEditFieldLabel = uilabel(app.MatrixPropertiesPanel);
            app.MatrixRangeEditFieldLabel.Text = 'Matrix Range:';
            app.MatrixRangeEditField = uieditfield(app.MatrixPropertiesPanel, 'text');
            app.Row_LabelsRangeEditFieldLabel = uilabel(app.MatrixPropertiesPanel);
            app.Row_LabelsRangeEditFieldLabel.Text = 'Row_Labels Range:';
            app.Row_LabelsRangeEditField = uieditfield(app.MatrixPropertiesPanel, 'text');
            app.Col_LabelsRangeEditFieldLabel = uilabel(app.MatrixPropertiesPanel);
            app.Col_LabelsRangeEditFieldLabel.Text = 'Col_Labels Range:';
            app.Col_LabelsRangeEditField = uieditfield(app.MatrixPropertiesPanel, 'text');
            app.ViewMatrixButton = uibutton(app.MatrixPropertiesPanel, 'push');
            app.ViewMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ViewMatrixButtonPushed, true);
            app.ViewMatrixButton.Text = 'View Matrix';
            app.ListTabsButton = uibutton(app.MatrixPropertiesPanel, 'push');
            app.ListTabsButton.ButtonPushedFcn = createCallbackFcn(app, @ListTabsButtonPushed, true);
            app.ListTabsButton.Text = 'List Tabs';
            app.UseaPreFormatedSheetButton = uibutton(app.BiMatPanel, 'push');
            app.UseaPreFormatedSheetButton.ButtonPushedFcn = createCallbackFcn(app, @UseaPreFormatedSheetButtonPushed, true);
            app.UseaPreFormatedSheetButton.Text = 'Use a Pre-Formated Sheet';
            app.FileDropDown = uidropdown(app.BiMatPanel);
            app.FileDropDown.Items = {'Test.xlsx', 'Bipart.xlsx', 'PowerGrid.xlsx'};
            app.FileDropDown.ValueChangedFcn = createCallbackFcn(app, @FileDropDownValueChanged, true);
            app.FileDropDown.Value = 'Test.xlsx';
            app.TabDropDown = uidropdown(app.BiMatPanel);
            app.TabDropDown.Items = {'Test1'};
            app.TabDropDown.ValueChangedFcn = createCallbackFcn(app, @TabDropDownValueChanged, true);
            app.TabDropDown.Value = 'Test1';
            app.FileNameexTestxlsxEditFieldLabel = uilabel(app.BiMatPanel);
            app.FileNameexTestxlsxEditFieldLabel.Text = 'File Name (ex. Test.xlsx): ';
            app.FileNameexTestxlsxEditField = uieditfield(app.BiMatPanel, 'text');
            app.FileNameexTestxlsxEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameexTestxlsxEditFieldValueChanged, true);
            app.FileNameexTestxlsxEditField.ValueChangingFcn = createCallbackFcn(app, @FileNameexTestxlsxEditFieldValueChanging, true);
            app.Panel = uipanel(app.BiMatPanel);
            app.RunButton = uibutton(app.Panel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StatusBar = uieditfield(app.Panel, 'text');
            app.Lamp = uilamp(app.Panel);
            app.GenerateButton = uibutton(app.Panel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.EditDefaultsButton = uibutton(app.Panel, 'push');
            app.EditDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @EditDefaultsButtonPushed, true);
            app.EditDefaultsButton.Text = 'Edit Defaults';
            app.PlotsDropDownLabel = uilabel(app.Panel);
            app.PlotsDropDownLabel.Text = 'Plots :';
            app.PlotsDropDown = uidropdown(app.Panel);
            app.PlotsDropDown.Items = {'Modular Graph', 'Matrix', 'Modular Matrix', 'Nested Matrix'};
            app.PlotsDropDown.Value = 'Modular Graph';
            app.ExportPanel = uipanel(app.BiMatPanel);
            app.ExportPanel.Title = 'Export Panel';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ExportPlotsCheckBox = uicheckbox(app.ExportPanel);
            app.ExportPlotsCheckBox.Text = 'Export Plots';
            app.FileEditFieldLabel = uilabel(app.ExportPanel);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.ExportPanel, 'text');
            app.TabLabel = uilabel(app.ExportPanel);
            app.TabLabel.Text = 'Tab:';
            app.TabEditField_2 = uieditfield(app.ExportPanel, 'text');
            app.ShowallPlotsButton = uibutton(app.ExportPanel, 'push');
            app.ShowallPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowallPlotsButtonPushed, true);
            app.ShowallPlotsButton.Text = 'Show all Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end