classdef MatrixPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        MatrixTable  matlab.ui.control.Table
        Label        matlab.ui.control.Label
    end

    methods (Access = private)
        
        function FillFigures(app, matrix, row_labels, col_labels)
            
            app.MatrixTable.Data = matrix; % Shows Adjacency Matrix
            app.MatrixTable.ColumnName = col_labels; % Shows Column Names
            app.MatrixTable.RowName = row_labels; % Shows Row Names
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, matrix, row_labels, col_labels, file, tab)
            
            FillFigures(app, matrix, row_labels, col_labels); % Calls the functions that shows the data
            
            app.Label.Text = append(file,' - ',tab); % Shows Matrix Title
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MatrixTable = uitable(app.UIFigure);
            app.MatrixTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.MatrixTable.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatrixPage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end