classdef ModulesPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        ModulesLabel             matlab.ui.control.Label
        ModuleLabel              matlab.ui.control.Label
        ModuleSpinner            matlab.ui.control.Spinner
        StatusBar                matlab.ui.control.EditField
        ShowMatrixButton         matlab.ui.control.Button
        AlgorithmEditFieldLabel  matlab.ui.control.Label
        AlgorithmEditField       matlab.ui.control.EditField
        RowLabels                matlab.ui.control.Table
        ColLabels                matlab.ui.control.Table
        matrix                   matlab.ui.control.Table
        NEditFieldLabel          matlab.ui.control.Label
        NEditField               matlab.ui.control.NumericEditField
        ViewModuleSizesButton    matlab.ui.control.Button
    end

%%% Properties that allow intialization of other windows %%%    
    properties (Access = private)
        ListPage
    end
    
    methods (Access = public)
        
        function FillMatrix(app, module_rows, module_cols, module_matrices, bp_row_labels, bp_col_labels)
            module_num = app.ModuleSpinner.Value;
            
            % Defines the Row and Col Indexes based on which module is selected
            row_indexes = module_rows{module_num};
            col_indexes = module_cols{module_num};
            
            % Creates Empty Lists for the Row and Col names
            row_names = string([]);
            col_names = string([]);
            
            % Selects the Row and Col names based on the Index Values
            for i=1:length(row_indexes)
                index = row_indexes(i);
                label = bp_row_labels(index);
                row_names(i) = label;
            end
            for i=1:length(col_indexes)
                col_names(1,i) = bp_col_labels(1,col_indexes(i));
            end
            
            app.UITable.Data = int8(module_matrices{module_num}); % Shows Module's Adjacency Matrix
            app.UITable.ColumnName = col_names; % Shows the sorted Column Names
            app.UITable.RowName = row_names; % Shows the sorted Row Names
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, algorithm, N, raw_matrix, bp_row_labels, bp_col_labels)
            % Fills the Hidden Edit Fields and Tables that will be used as Global Variables
            app.AlgorithmEditField.Value = algorithm;
            app.NEditField.Value = N;
            app.RowLabels.Data = bp_row_labels;
            app.ColLabels.Data = bp_col_labels;
            app.matrix.Data = raw_matrix;
        end

        % Value changed function: ModuleSpinner
        function ModuleSpinnerValueChanged(app, event)
            % Clears the Data Table
            app.UITable.Data = [];
            app.UITable.ColumnName = [];
            app.UITable.RowName = [];
        end

        % Button pushed function: ShowMatrixButton
        function ShowMatrixButtonPushed(app, event)
            % Defines the Global Variables from the Hidden Edit Fields and Tables
            module_num = app.ModuleSpinner.Value;
            algorithm = app.AlgorithmEditField.Value;
            raw_matrix = app.matrix.Data;
            bp = Bipartite(raw_matrix);
            N = app.NEditField.Value;
            bp_row_labels = app.RowLabels.Data;
            bp_col_labels = app.ColLabels.Data;
            
            % Selecting Algorithm with the Drop Down
            % LeadingEigenVector
            if strcmp(algorithm, 'LeadingEigenVector') == true
                bp.community = LeadingEigenvector(bp.matrix);
                                               
            % ApadtiveBrim
            elseif strcmp(algorithm, 'AdaptiveBrim') == true
                bp.community = AdaptiveBrim(bp.matrix);
                
            % LPBrim
            elseif strcmp(algorithm, 'LPBrim') == true
                bp.community = LPBrim(bp.matrix);  
            end
            
            obj = Detect(bp.community); 
            [module_rows, module_cols] = ExtractCommunityIndexes(obj); % Defines the Indexes of Each Node within each Module
            module_matrices = ExtractCommunityMatrices(obj); % Defines the Adjancecy Matrix of each Module
            
            % Verifies that the Selected Moudle Number is actually defined
            if module_num <= N
                FillMatrix(app, module_rows, module_cols, module_matrices, bp_row_labels, bp_col_labels);
            else
                app.StatusBar.Value = "Selected Module does not exist";
                app.StatusBar.FontWeight = 'bold';
                app.StatusBar.FontColor = 'red';
            end
        end

        % Button pushed function: ViewModuleSizesButton
        function ViewModuleSizesButtonPushed(app, event)
            % Defines the Global Variables from the Hidden Edit Fields and Tables
            algorithm = app.AlgorithmEditField.Value;
            raw_matrix = app.matrix.Data;
            bp = Bipartite(raw_matrix);
            N = app.NEditField.Value;
            
            % Selecting Algorithm with the Drop Down
            % LeadingEigenVector
            if strcmp(algorithm, 'LeadingEigenVector') == true
                
                bp.community = LeadingEigenvector(bp.matrix);
                                               
            % ApadtiveBrim
            elseif strcmp(algorithm, 'AdaptiveBrim') == true
                bp.community = AdaptiveBrim(bp.matrix);
                
            % LPBrim
            elseif strcmp(algorithm, 'LPBrim') == true
                bp.community = LPBrim(bp.matrix);  
            end
            
            obj = Detect(bp.community);
            module_matrices = ExtractCommunityMatrices(obj); % Defines the Adjancecy Matrix of each Module
            module_sizes = string([]); % Creates an Empty Array for the sizes to be appended to
            
            % Converts each cell of the base module_sizes matrix into a string
            for i=1:N 
                module_sizes(i) = append(string(size(module_matrices{i},1)), 'x', string(size(module_matrices{i},2)));
            end
                        
            app.ListPage = ListPage(transpose(module_sizes)); % Opens the ListPage
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.ModulesLabel = uilabel(app.UIFigure);
            app.ModulesLabel.Text = {'Modules'; ''};
            app.ModuleLabel = uilabel(app.UIFigure);
            app.ModuleLabel.Text = 'Module:';
            app.ModuleSpinner = uispinner(app.UIFigure);
            app.ModuleSpinner.Limits = [1 Inf];
            app.ModuleSpinner.ValueChangedFcn = createCallbackFcn(app, @ModuleSpinnerValueChanged, true);
            app.ModuleSpinner.Value = 1;
            app.StatusBar = uieditfield(app.UIFigure, 'text');
            app.ShowMatrixButton = uibutton(app.UIFigure, 'push');
            app.ShowMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMatrixButtonPushed, true);
            app.ShowMatrixButton.Text = 'Show Matrix';
            app.AlgorithmEditFieldLabel = uilabel(app.UIFigure);
            app.AlgorithmEditFieldLabel.Visible = 'off';
            app.AlgorithmEditFieldLabel.Text = 'Algorithm';
            app.AlgorithmEditField = uieditfield(app.UIFigure, 'text');
            app.AlgorithmEditField.Visible = 'off';
            app.RowLabels = uitable(app.UIFigure);
            app.RowLabels.ColumnName = '';
            app.RowLabels.RowName = {};
            app.RowLabels.Visible = 'off';
            app.ColLabels = uitable(app.UIFigure);
            app.ColLabels.ColumnName = '';
            app.ColLabels.RowName = {};
            app.ColLabels.Visible = 'off';
            app.matrix = uitable(app.UIFigure);
            app.matrix.ColumnName = '';
            app.matrix.RowName = {};
            app.matrix.Visible = 'off';
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Visible = 'off';
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Visible = 'off';
            app.ViewModuleSizesButton = uibutton(app.UIFigure, 'push');
            app.ViewModuleSizesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewModuleSizesButtonPushed, true);
            app.ViewModuleSizesButton.Text = 'View Module Sizes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModulesPage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end