classdef PandZPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        PTable                  matlab.ui.control.Table
        zTable                  matlab.ui.control.Table
        labelsTable             matlab.ui.control.Table
        ResetButton             matlab.ui.control.Button
        rolesTable              matlab.ui.control.Table
        role_col                matlab.ui.control.Table
        ExportScreenshotButton  matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        selection 
    end
    
    methods (Access = public)
        
        function PlotPandZ(app, labels, p, z, roles)
            
            N = length(p); %Sets the length of the iteration
            
            % Intializes the empty grouped data arrays
            ultra_peripheral = strings([N,4]);
            peripheral = strings([N,4]);
            nonhub_connector = strings([N,4]);
            nonhub_kinless = strings([N,4]);
            provincial = strings([N,4]);
            connector = strings([N,4]);
            kinless = strings([N,4]);
            
            % Puts the Data into Groups based on its role
            for i=1:N
                % Non-Hub Roles
                if z(i) < 2.5
                    if p(i) <= .1
                        ultra_peripheral(i,1) = labels(i);
                        ultra_peripheral(i,2) = p(i);
                        ultra_peripheral(i,3) = z(i);
                        ultra_peripheral(i,4) = "NonHub-Ultra-Peripheral";
                    elseif p(i) >= .1 && p(i) <= .625
                        peripheral(i,1) = labels(i);
                        peripheral(i,2) = p(i);
                        peripheral(i,3) = z(i);
                        peripheral(i,4) = "NonHub-Peripheral";
                    elseif p(i) >= .625 && p(i) <= .8
                        nonhub_connector(i,1) = labels(i);
                        nonhub_connector(i,2) = p(i);
                        nonhub_connector(i,3) = z(i);
                        nonhub_connector(i,4) = "NonHub-Connector";
                    elseif p(i) >= .8
                        nonhub_kinless(i,1) = labels(i);
                        nonhub_kinless(i,2) = p(i);
                        nonhub_kinless(i,3) = z(i);
                        nonhub_kinless(i,4) = "NonHub-Kinless";
                    end
                % Hub Roles
                elseif z(i) >= 2.5
                    if p(i) <= .3
                        provincial(i,1) = labels(i);
                        provincial(i,2) = p(i);
                        provincial(i,3) = z(i);
                        provincial(i,4) = "Hub-Provincial";
                    elseif p(i) >= .3 && p(i) <= .75
                        connector(i,1) = labels(i);
                        connector(i,2) = p(i);
                        connector(i,3) = z(i);
                        connector(i,4) = "Hub-Connector";
                    elseif p(i) >= .75
                        kinless(i,1) = labels(i);
                        kinless(i,2) = p(i);
                        kinless(i,3) = z(i);
                        kinless(i,4) = "Hub-Kinless";
                    end
                end
            end      
            
            sz = 50; %Marker Size
            
            % Plotting Each Subgroup only when there is data within the generated frame
            if isempty(ultra_peripheral) == 0
                x = str2double(ultra_peripheral(:,2)); % converts the strings from the subgroup arrays into usable numbers
                y = str2double(ultra_peripheral(:,3)); % converts the strings from the subgroup arrays into usable numbers
                plot = scatter(app.UIAxes, x, y, sz,[0.39,0.83,0.07],'LineWidth', 1);
                plot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',ultra_peripheral(:,1));
                plot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',ultra_peripheral(:,2));
                plot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',ultra_peripheral(:,3));
                plot.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',ultra_peripheral(:,4));
                hold(app.UIAxes, 'on'); % Allows for the next group to be plotted on the same axis
            end
            
            if isempty(peripheral) == 0
                x = str2double(peripheral(:,2)); % converts the strings from the subgroup arrays into usable numbers
                y = str2double(peripheral(:,3)); % converts the strings from the subgroup arrays into usable numbers
                plot = scatter(app.UIAxes, x, y, sz,[0.30,0.75,0.93],'LineWidth', 1);
                plot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',peripheral(:,1));
                plot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',peripheral(:,2));
                plot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',peripheral(:,3));
                plot.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',peripheral(:,4));
                hold(app.UIAxes, 'on'); % Allows for the next group to be plotted on the same axis
            end
            
            if isempty(nonhub_connector) == 0
                x = str2double(nonhub_connector(:,2)); % converts the strings from the subgroup arrays into usable numbers
                y = str2double(nonhub_connector(:,3)); % converts the strings from the subgroup arrays into usable numbers
                plot = scatter(app.UIAxes, x, y, sz,[0.49,0.18,0.56],'LineWidth', 1);
                plot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',nonhub_connector(:,1));
                plot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',nonhub_connector(:,2));
                plot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',nonhub_connector(:,3));
                plot.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',nonhub_connector(:,4));
                hold(app.UIAxes, 'on'); % Allows for the next group to be plotted on the same axis
            end
            
            if isempty(nonhub_kinless) == 0
                x = str2double(nonhub_kinless(:,2)); % converts the strings from the subgroup arrays into usable numbers
                y = str2double(nonhub_kinless(:,3)); % converts the strings from the subgroup arrays into usable numbers
                plot = scatter(app.UIAxes, x, y, sz,[1.00,0.00,1.00],'LineWidth', 1);
                plot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',nonhub_kinless(:,1));
                plot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',nonhub_kinless(:,2));
                plot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',nonhub_kinless(:,3));
                plot.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',nonhub_kinless(:,4));
                hold(app.UIAxes, 'on'); % Allows for the next group to be plotted on the same axis
            end
            
            if isempty(provincial) == 0
                x = str2double(provincial(:,2)); % converts the strings from the subgroup arrays into usable numbers
                y = str2double(provincial(:,3)); % converts the strings from the subgroup arrays into usable numbers
                plot = scatter(app.UIAxes, x, y, sz,[1.00,0.00,0.00],'LineWidth', 1);
                plot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',provincial(:,1));
                plot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',provincial(:,2));
                plot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',provincial(:,3));
                plot.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',provincial(:,4));
                hold(app.UIAxes, 'on');  % Allows for the next group to be plotted on the same axis
            end
           
            if isempty(connector) == 0
                x = str2double(connector(:,2)); % converts the strings from the subgroup arrays into usable numbers
                y = str2double(connector(:,3)); % converts the strings from the subgroup arrays into usable numbers
                plot = scatter(app.UIAxes, x, y, sz,[0.93,0.69,0.13],'LineWidth', 1);
                plot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',connector(:,1));
                plot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',connector(:,2));
                plot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',connector(:,3));
                plot.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',connector(:,4));
                hold(app.UIAxes, 'on'); % Allows for the next group to be plotted on the same axis
            end
            
            if isempty(kinless) == 0
                x = str2double(kinless(:,2)); % converts the strings from the subgroup arrays into usable numbers
                y = str2double(kinless(:,3)); % converts the strings from the subgroup arrays into usable numbers
                plot = scatter(app.UIAxes, x, y, sz,[1.00,1.00,0.00],'LineWidth', 1);
                plot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',kinless(:,1));
                plot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',kinless(:,2));
                plot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',kinless(:,3));
                plot.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',kinless(:,4));
                hold(app.UIAxes, 'on'); % Allows for the next group to be plotted on the same axis
            end
                       
            lower_bound = min(z) - 1; % Defines the lower y-limit based on the minimum z value
            upper_bound = max(z) + 1; % Defines the upper y-limit based on the maximum z value
             
            xlim(app.UIAxes, [-0.1,1.1]) % Applies the x-limits to the plot
            ylim(app.UIAxes, [lower_bound, upper_bound]) % Applies the y-limits to the plot
            
            % Plotting the asymptotes that seperate the subgroups
            line(app.UIAxes, [-0.1,1.1],[2.5,2.5], 'color', 'black');
            line(app.UIAxes, [0.02,0.02],[lower_bound,2.5], 'color', 'black');
            line(app.UIAxes, [0.3,0.3],[2.5,upper_bound], 'color', 'black');
            line(app.UIAxes, [0.625,0.625],[lower_bound,2.5], 'color', 'black');
            line(app.UIAxes, [0.8,0.8],[lower_bound,2.5], 'color', 'black');
            line(app.UIAxes, [0.75,0.75],[2.5,upper_bound], 'color', 'black');
            
            % Defines the Legend's information and location
            legend(app.UIAxes,["Ultra-Peripheral", "NonHub-Peripheral", "NonHub-Connector", "NonHub-Kinless", "Provincial", "Connector", "Kinless"], 'location', 'eastoutside','AutoUpdate','off');        
            hold(app.UIAxes, 'on'); % allows for the Cell Sections to be added to the same axes
            
            app.rolesTable.Data = roles;  % fills in the role table for Cells to be selected
                          
        end
    
        
        function ResetPlotLabels(app)
            % These Values are Defined behind the roles table and they are made invisible in the startupfcn()
            labels = app.labelsTable.Data;
            p = app.PTable.Data;
            z = app.zTable.Data;
            roles = app.rolesTable.Data;
            role_list = app.role_col.Data;
            
            cla(app.UIAxes) % Clears figure
            
            PlotPandZ(app, labels, p, z, roles); % Replots data
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, file, tab, labels, p, z, label, roles, role_col)
            app.Label.Text = append('P and Z Scores','-',file,' - ',tab, ' - ', label);
           
            app.labelsTable.Visible = 'off';
            app.labelsTable.Data = labels;
            app.PTable.Visible = 'off';
            app.PTable.Data = p;
            app.zTable.Visible = 'off';
            app.zTable.Data = z;
            app.role_col.Visible = 'off';
            app.role_col.Data = role_col;
            
            PlotPandZ(app, labels, p, z, roles);
        end

        % Cell selection callback: rolesTable
        function rolesTableCellSelection(app, event)
            cell = event.Indices;
            
            labels = app.labelsTable.Data;
            p = app.PTable.Data;
            z = app.zTable.Data;
            role_list = app.role_col.Data;
            role_matrx = app.rolesTable.Data;
            
            selected_cell = role_matrx(cell(1,1),cell(1,2));
            
            N = length(labels);
            label = strings([N,1]);
            role = strings([N,1]);
            
            % Check for the selected cell within the original data and re-plots it with different parameters
            for i=1:N
              if strcmp(selected_cell,labels(i))
                  
                  label(:,1) = labels(i);
                  role(:,1) = role_list(i);
                                   
                  pt = scatter(app.UIAxes, p(i), z(i), 'filled','p'); % Plots
                  pt.DataTipTemplate.DataTipRows(1) = dataTipTextRow('Node:',label); %Hovering Label row 1
                  pt.DataTipTemplate.DataTipRows(2) = dataTipTextRow('P:',p(i)); %Hovering Label row 2
                  pt.DataTipTemplate.DataTipRows(3) = dataTipTextRow('z:',z(i)); %Hovering Label row 3
                  pt.DataTipTemplate.DataTipRows(4) = dataTipTextRow('Role:',role); %Hovering Label row 4
                  hold(app.UIAxes, 'on');
                  
                  text(app.UIAxes, p(i)+0.01,z(i)+0.02,labels(i)); %Fixed Label
                  hold(app.UIAxes, 'on');
                  
              end
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            ResetPlotLabels(app);
        end

        % Button pushed function: ExportScreenshotButton
        function ExportScreenshotButtonPushed(app, event)
            filename = app.Label.Text;
            path = "BiMat_Analysis\Results\P and Z\";
            location = append(path, filename, ".jpg");
            exportapp(app.UIFigure,location)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'P and Z Scores';
            app.PTable = uitable(app.UIFigure);
            app.PTable.ColumnName = {'P'};
            app.PTable.RowName = {};
            app.PTable.Visible = 'off';
            app.zTable = uitable(app.UIFigure);
            app.zTable.ColumnName = {'z'};
            app.zTable.RowName = {};
            app.zTable.Visible = 'off';
            app.labelsTable = uitable(app.UIFigure);
            app.labelsTable.ColumnName = {'labels'};
            app.labelsTable.RowName = {};
            app.labelsTable.Visible = 'off';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.rolesTable = uitable(app.UIFigure);
            app.rolesTable.ColumnName = {'Ultra-Peripheral'; 'Non-Hub Peripheral'; 'Non-Hub Connectors'; 'Non-Hub Kinless'; 'Provincial Hubs'; 'Connector Hubs'; 'Kinless Hubs'};
            app.rolesTable.RowName = {};
            app.rolesTable.CellSelectionCallback = createCallbackFcn(app, @rolesTableCellSelection, true);
            app.role_col = uitable(app.UIFigure);
            app.role_col.ColumnName = {'role'};
            app.role_col.RowName = {};
            app.role_col.Visible = 'off';
            app.ExportScreenshotButton = uibutton(app.UIFigure, 'push');
            app.ExportScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @ExportScreenshotButtonPushed, true);
            app.ExportScreenshotButton.Text = 'Export Screenshot';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Participation Coefficent: P')
            ylabel(app.UIAxes, 'Within-Module Degree: z')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PandZPage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end